classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        startButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        state
A
B
K
dt = 0.01
t = 0
timerObj
running = false
M
m_pend
b_fric
L
g
p % Description
    end
    
    methods (Access = private)
        
        function results = stepPhysics(app)
            if ~app.running
    return
end

F = -app.K * app.state;
x_dot = app.A * app.state + app.B * F;
app.state = app.state + x_dot * app.dt;

cart_x = app.state(1);
theta = app.state(3);

bob_x = cart_x + app.L * sin(theta);
bob_y = app.L * cos(theta);


cla(app.UIAxes)
hold(app.UIAxes, 'on')   
% CART (make it RED for visibility)
rectangle(app.UIAxes, 'Position', [cart_x-0.2, 0, 0.4, 0.1], ...
    'FaceColor', 'r');

% ROD (thick and white)
line(app.UIAxes, [cart_x, bob_x], [0, bob_y], ...
    'LineWidth', 4, 'Color', 'w');

% PIVOT
plot(app.UIAxes, cart_x, 0, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g');

% BOB
plot(app.UIAxes, bob_x, bob_y, 'ro', ...
    'MarkerSize', 10, 'MarkerFaceColor', 'r');

hold(app.UIAxes, 'off')

xlim(app.UIAxes, [-2 2])
ylim(app.UIAxes, [-0.5 1])
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.M = 1.0;
app.m_pend = 0.1;
app.b_fric = 0.1;
app.L = 0.5;
app.g = 9.81;

app.p = (app.M + app.m_pend)*(app.m_pend*app.L^2) - (app.m_pend*app.L)^2;

app.A = [0, 1, 0, 0;
         0, -(app.m_pend*app.L^2)*app.b_fric/app.p, (app.m_pend^2*app.g*app.L^2)/app.p, 0;
         0, 0, 0, 1;
         0, -(app.m_pend*app.L*app.b_fric)/app.p, app.m_pend*app.g*app.L*(app.M+app.m_pend)/app.p, 0];

app.B = [0;
         app.m_pend*app.L^2/app.p;
         0;
         -app.m_pend*app.L/app.p];

Q = diag([10,1,100,10]);
R = 1;
app.K = lqr(app.A, app.B, Q, R);

app.state = [0;0;0.1;0];

app.timerObj = timer;
app.timerObj.ExecutionMode = 'fixedRate';
app.timerObj.Period = app.dt;
app.timerObj.TimerFcn = @(~,~) app.stepPhysics();
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
if ~app.running
    
    start(app.timerObj);
    app.running = true;

else
    
    stop(app.timerObj);
    app.running = false;

    % 🔥 ADD THIS LINE (CRITICAL)
    app.state = [0;0;0.1;0];

end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end